classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        NextButton  matlab.ui.control.Button
        PrevButton  matlab.ui.control.Button
        UITable     matlab.ui.control.Table
        LOADButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Tensor      double      
        SliceIndex  double = 1  
        NumSlices   double = 1
    end
    
    methods (Access = private)
        
        function updateTable(app)
            slice = app.Tensor(:,:,app.SliceIndex);
            
            app.UITable.Data = slice;
            
            %Auto column names
            app.UITable.ColumnName = ...
                arrayfun(@(x) sprintf('Col %d',x), ...
                         1:size(slice,2), 'UniformOutput', false);
            
            % Show slice number in title
            app.UIFigure.Name = sprintf('Slice %d / %d', ...
                                        app.SliceIndex, app.NumSlices);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            [filename, folderpath] = uigetfile('*.mat');
            if isequal(filename,0)
                return
            end
        
            S = load(fullfile(folderpath, filename));
            fn = fieldnames(S);
            app.Tensor = S.(fn{1});   % n x m x t
        
            if ndims(app.Tensor) ~= 3
                uialert(app.UIFigure,'Data must be n x m x t','Invalid Data');
                return
            end
            app.SliceIndex = 1;
            app.NumSlices  = size(app.Tensor,3);
        
            updateTable(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if isempty(app.Tensor)
                return
            end
            
            app.SliceIndex = min(app.SliceIndex + 1, app.NumSlices);
            updateTable(app)
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if isempty(app.Tensor)
                return
            end

            app.SliceIndex = max(app.SliceIndex - 1, 1);
            updateTable(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end